classdef report1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        computeButton  matlab.ui.control.Button
        loadButton     matlab.ui.control.Button
        afterImage     matlab.ui.control.UIAxes
        beforeImage    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            filename = uigetfile({'*.png;*.jpeg','Image files (*.png,*.jpeg)'});
            % read image
            im=imread(filename);
            % % plot image
            imshow(im, "Parent", app.beforeImage);
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            filename = uigetfile({'*.png;*.jpeg','Image files (*.png,*.jpeg)'});
            % read image
            im=imread(filename);
            % compute image
            imgray = rgb2gray(im);
            BW = imbinarize(imgray);
            % plot images
            imshow(BW*255, "Parent", app.afterImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.beforeImage = uiaxes(app.UIFigure);
            zlabel(app.beforeImage, 'Z')
            app.beforeImage.XTick = [];
            app.beforeImage.YTick = [];
            app.afterImage = uiaxes(app.UIFigure);
            zlabel(app.afterImage, 'Z')
            app.afterImage.XTick = [];
            app.afterImage.YTick = [];
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = report1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end